classdef ProgressIndicatorV34 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        GaugeTab          matlab.ui.container.Tab
        Panel             matlab.ui.container.Panel
        GridLayout        matlab.ui.container.GridLayout
        GCircLbl          matlab.ui.control.Label
        TInfoGC           matlab.ui.container.Panel
        GridLayout_2      matlab.ui.container.GridLayout
        GC_RecNumLbl      matlab.ui.control.Label
        GC_RecNumVal      matlab.ui.control.Label
        GC_TimePerRecLbl  matlab.ui.control.Label
        GC_TimePerRecVal  matlab.ui.control.Label
        GC_TimePassedVal  matlab.ui.control.Label
        GC_TimePassedLbl  matlab.ui.control.Label
        GC_TimeRemLbl     matlab.ui.control.Label
        GC_TimeRemVal     matlab.ui.control.Label
        GC_TotalDurLbl    matlab.ui.control.Label
        GC_TotalDurVal    matlab.ui.control.Label
        GCirc             matlab.ui.control.Gauge
        GCOverflowLamp    matlab.ui.control.Lamp
        DegTab            matlab.ui.container.Tab
        GridLayout2       matlab.ui.container.GridLayout
        GC90DLbl          matlab.ui.control.Label
        TInfoG9           matlab.ui.container.Panel
        GridLayout_3      matlab.ui.container.GridLayout
        G9_RecNumLbl      matlab.ui.control.Label
        G9_RecNumVal      matlab.ui.control.Label
        G9_TimePerRecLbl  matlab.ui.control.Label
        G9_TimePerRecVal  matlab.ui.control.Label
        G9_TimePassedVal  matlab.ui.control.Label
        G9_TimePassedLbl  matlab.ui.control.Label
        G9_TimeRemLbl     matlab.ui.control.Label
        G9_TimeRemVal     matlab.ui.control.Label
        G9_TotalDurLbl    matlab.ui.control.Label
        G9_TotalDurVal    matlab.ui.control.Label
        G9OverflowLamp    matlab.ui.control.Lamp
        G90D              matlab.ui.control.NinetyDegreeGauge
        LinearTab         matlab.ui.container.Tab
        Panel_2           matlab.ui.container.Panel
        GridLayout3       matlab.ui.container.GridLayout
        GLine             matlab.ui.control.LinearGauge
        TInfoGL           matlab.ui.container.Panel
        GridLayout_4      matlab.ui.container.GridLayout
        GL_RecNumLbl      matlab.ui.control.Label
        GL_RecNumVal      matlab.ui.control.Label
        GL_TimePerRecLbl  matlab.ui.control.Label
        GL_TimePerRecVal  matlab.ui.control.Label
        GL_TimePassedVal  matlab.ui.control.Label
        GL_TimePassedLbl  matlab.ui.control.Label
        GL_TimeRemLbl     matlab.ui.control.Label
        GL_TimeRemVal     matlab.ui.control.Label
        GL_TotalDurLbl    matlab.ui.control.Label
        GL_TotalDurVal    matlab.ui.control.Label
        GLineLbl          matlab.ui.control.Label
        GLOverflowLamp    matlab.ui.control.Lamp
        SemiCircTab       matlab.ui.container.Tab
        Panel_3           matlab.ui.container.Panel
        GridLayout4       matlab.ui.container.GridLayout
        TInfoGC_2         matlab.ui.container.Panel
        GridLayout_5      matlab.ui.container.GridLayout
        GS_RecNumLbl      matlab.ui.control.Label
        GS_RecNumVal      matlab.ui.control.Label
        GS_TimePerRecLbl  matlab.ui.control.Label
        GS_TimePerRecVal  matlab.ui.control.Label
        GS_TimePassedVal  matlab.ui.control.Label
        GS_TimePassedLbl  matlab.ui.control.Label
        GS_TimeRemLbl     matlab.ui.control.Label
        GS_TimeRemVal     matlab.ui.control.Label
        GS_TotalDurLbl    matlab.ui.control.Label
        GS_TotalDurVal    matlab.ui.control.Label
        GSemiC            matlab.ui.control.SemicircularGauge
        GSCOverflowLamp   matlab.ui.control.Lamp
        GSemiCLbl         matlab.ui.control.Label
        FastGaugeTab      matlab.ui.container.Tab
        GridLayout5       matlab.ui.container.GridLayout
        TInfoG9_2         matlab.ui.container.Panel
        GridLayout_6      matlab.ui.container.GridLayout
        GF_RecNumLbl      matlab.ui.control.Label
        GF_RecNumVal      matlab.ui.control.Label
        GF_TimePerRecLbl  matlab.ui.control.Label
        GF_TimePerRecVal  matlab.ui.control.Label
        GF_TimePassedVal  matlab.ui.control.Label
        GF_TimePassedLbl  matlab.ui.control.Label
        GF_TimeRemLbl     matlab.ui.control.Label
        GF_TimeRemVal     matlab.ui.control.Label
        GF_TotalDurLbl    matlab.ui.control.Label
        GF_TotalDurVal    matlab.ui.control.Label
        GridLayout6       matlab.ui.container.GridLayout
        FastGauge1        matlab.ui.control.SemicircularGauge
        FastGauge2        matlab.ui.control.Gauge
        GFOverflowLamp    matlab.ui.control.Lamp
        GCF0DLbl          matlab.ui.control.Label
    end

    
    properties (Access = private)
        ProgressVal = nan;% Description
        TimingInfoExists = false % Description
        MaxRecordsCount;
        InputTic;
    end
    properties (Access = public)
        TicTime; % Description
        DedicatedTimer = [];
        CurrentProgressVal;
        CurrentRecNum;
        User_StateInfo;
    end
    
    methods (Access = private)
        
        function Timing = ProjectedFinishCalculator( app ,TimingInput)
            app.InputTic = TimingInput{1};
            InputToc = toc(app.InputTic);
            CurrentRecord = TimingInput{2};
            Timing = [];
            MaxRecordsCount = TimingInput{3};
            app.CurrentRecNum = CurrentRecord;
            Timing.MaxRecordsCount = MaxRecordsCount;
            if length(TimingInput) <= 3
                StartingRecord = 1;
            else
                StartingRecord = TimingInput{4};
            end
            Timing.Percentage = CurrentRecord*100/MaxRecordsCount;
            Timing.CurrentRecNum = CurrentRecord;
            Timing.PassedTime = InputToc;
            Timing.TotalDuration = (MaxRecordsCount*Timing.PassedTime)/(CurrentRecord-StartingRecord+1);
            Timing.TimePerRecms = 1000*Timing.PassedTime/(CurrentRecord-StartingRecord+1);
            Timing.TimePassed = app.GenerateTotalDurationStr(Timing.PassedTime);
            %             Timing.FinisheDateTime = datestr(now+datenum(0,0,0,0,0,1)*(MaxRecordsCount-CurrentRecord)*Timing.PassedTime/(CurrentRecord-StartingRecord+1),'mm-dd HH:MM:SS');
            Timing.TimeRemaining = (MaxRecordsCount-CurrentRecord)*Timing.PassedTime/(CurrentRecord-StartingRecord+1);
        end
        
        function Output = GenerateTotalDurationStr(~,TotalDur)
            Strings =     {'Month','Day','Hr','Min','Sec'};
            Multipliers = [   30  , 24  , 60   , 60     , 1      ];
            
            Output = '';
            TempDur = TotalDur;
            for ctr = 1:length(Multipliers)
                % 			prod(Multipliers(ctr:end))
                TotalTime = floor(TempDur / prod(Multipliers(ctr:end)));
                if TotalTime > 0
                    if TotalTime == 1
                        TimeSingle = '';
                    else
                        TimeSingle = 's';
                    end
                    Output = [Output sprintf('%2u %s%s, ',TotalTime,Strings{ctr},TimeSingle)];
                end
                TempDur = TempDur - TotalTime*prod(Multipliers(ctr:end));
            end
        end
        
        
        function UpdateTimingStrings(app,TimingInput)
            Timing = app.ProjectedFinishCalculator(TimingInput);
            TimePassedStr    = app.GenerateTotalDurationStr(Timing.PassedTime);
            TimeRemainingStr = app.GenerateTotalDurationStr(Timing.TimeRemaining);
            TotalDurationStr = app.GenerateTotalDurationStr(Timing.TotalDuration);
            [TimeVal, UnitVal] = app.SetTimingStraight(Timing.TimePerRecms);
            TimePerSecondStr = sprintf('%8.2f [%s]',TimeVal,UnitVal);
            RecNumStr  = sprintf('%u of %u',Timing.CurrentRecNum,Timing.MaxRecordsCount);
            
            app.G9_TimePassedVal.Text = TimePassedStr;
            app.GF_TimePassedVal.Text = TimePassedStr;
            app.GC_TimePassedVal.Text = TimePassedStr;
            app.GS_TimePassedVal.Text = TimePassedStr;
            app.GL_TimePassedVal.Text = TimePassedStr;
            
            app.G9_TimeRemVal.Text = TimeRemainingStr;
            app.GF_TimeRemVal.Text = TimeRemainingStr;
            app.GC_TimeRemVal.Text = TimeRemainingStr;
            app.GS_TimeRemVal.Text = TimeRemainingStr;
            app.GL_TimeRemVal.Text = TimeRemainingStr;
            
            app.G9_TotalDurVal.Text = TotalDurationStr;
            app.GF_TotalDurVal.Text = TotalDurationStr;
            app.GC_TotalDurVal.Text = TotalDurationStr;
            app.GS_TotalDurVal.Text = TotalDurationStr;
            app.GL_TotalDurVal.Text = TotalDurationStr;
            
            app.G9_RecNumVal.Text = RecNumStr;
            app.GF_RecNumVal.Text = RecNumStr;
            app.GC_RecNumVal.Text = RecNumStr;
            app.GS_RecNumVal.Text = RecNumStr;
            app.GL_RecNumVal.Text = RecNumStr;
            
            app.G9_TimePerRecVal.Text = TimePerSecondStr;
            app.GF_TimePerRecVal.Text = TimePerSecondStr;
            app.GC_TimePerRecVal.Text = TimePerSecondStr;
            app.GS_TimePerRecVal.Text = TimePerSecondStr;
            app.GL_TimePerRecVal.Text = TimePerSecondStr;

        end

        function [OutVal, OutUnit] = SetTimingStraight(app,InpMiliSecond)
            OutVal = InpMiliSecond; OutUnit = 'mSec';
            if InpMiliSecond > 1 && InpMiliSecond < 1000
                OutVal = InpMiliSecond;     OutUnit = 'mSec';
                return
            else
                InpTime = InpMiliSecond/1000;
                InpUnit = 'Sec';
            end
            
            if InpTime > 1 && InpTime < 60
                OutVal = InpTime;           OutUnit = InpUnit;
                return;
            else
                InpTime = InpTime/60;
                InpUnit = 'Hour';
            end
            
            if InpTime > 1 && InpTime < 60
                OutVal = InpTime;           OutUnit = InpUnit;
                return;
            else
                InpTime = InpTime/24;
                InpUnit = 'Days';
            end
        end
    end


methods (Access = public)
    
    function results = UpdateProcess(app,ProgressVal) %this function
        %             InputToc = TimingInput{1};
        %             CurrentRecord = TimingInput{2};
        %             MaxRecordsCount = TimingInput{3};
        %             StartingRecord = TimingInput{4};

        if nargin > 1
            app.CurrentProgressVal = ProgressVal;
        else
            ProgressVal = app.CurrentProgressVal;
        end

        if isempty(app.DedicatedTimer)
            app.DedicatedTimer = timer('Period',5,'ExecutionMode','fixedRate');
            app.DedicatedTimer.TimerFcn = @(b,a) app.UpdateProcess();
            start(app.DedicatedTimer); 
        end
        
        if iscell(ProgressVal)
            if length(ProgressVal) ~= 4
                fprintf(['\n\n what the function requires:'...
                    '\n\tInputToc = TimingInput{1}'...
                    '\n\tCurrentRecord = TimingInput{2};'...
                    '\n\tMaxRecordsCount = TimingInput{3};',...
                    '\n\tStartingRecord = TimingInput{4};\n']);
            end
            app.UpdateTimingStrings(ProgressVal);
        else
            if ProgressVal > 100
                app.GCOverflowLamp.Visible = true;
                app.G9OverflowLamp.Visible = true;
                app.GFOverflowLamp.Visible = true;
                app.GLOverflowLamp.Visible = true;
                app.G9OverflowLamp.Visible = true;
                ProgressStr = 'Overflown!';
            elseif ProgressVal < 0
                app.GCOverflowLamp.Visible = true;
                app.G9OverflowLamp.Visible = true;
                app.GFOverflowLamp.Visible = true;
                app.GLOverflowLamp.Visible = true;
                app.G9OverflowLamp.Visible = true;
                ProgressStr = 'Underflown!';
            else
                app.GCOverflowLamp.Visible = false;
                app.G9OverflowLamp.Visible = false;
                app.GFOverflowLamp.Visible = false;
                app.GLOverflowLamp.Visible = false;
                app.G9OverflowLamp.Visible = false;
                ProgressStr = sprintf('Progress: %5.2f%%',ProgressVal);
            end
            
            app.ProgressVal = ProgressVal;
            app.GCirc.Value = app.ProgressVal;

            app.FastGauge2.Value  = mod(app.CurrentRecNum,51);
            app.FastGauge1.Value  = app.ProgressVal;
            
            
            app.G90D.Value  = app.ProgressVal;
            app.GLine.Value = app.ProgressVal;
            app.GSemiC.Value= app.ProgressVal;
            app.GCircLbl.Text  = ProgressStr;
            app.GC90DLbl.Text  = ProgressStr;
            app.GCF0DLbl.Text  = ProgressStr;
            app.GLineLbl.Text  = ProgressStr;
            app.GSemiCLbl.Text = ProgressStr;
            
            if nargout >= 1
                results = {ProgressVal,ProgressStr};
            end
        end
        drawnow;
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Startup_ProgressIndicator(app, AppName, varargin)
        if exist('AppName','var')
            app.UIFigure.Name = AppName;
        end
        if exist('varargin','var')
            for iCtr=1:length(varargin)
                if strcmpi(varargin{iCtr}{1},'PrjCalc')
                    TimingInput = varargin{iCtr}{2};
                    app.UpdateTiming(TimingInput);
                end
            end
        end
        drawnow;
        pause(0.1);
        end

        % Callback function
        function WindowChangedSize(app, event)
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if strcmpi(class(app.DedicatedTimer),'timer') == 1
                app.DedicatedTimer.stop();
                Ctr = 1;
                while(strcmpi(app.DedicatedTimer.Running,'on')==1)
                    Ctr=Ctr+1;
                    pause(0.1);
                    if Ctr >= 30
                        break;
                    end
                end
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UIFigure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GaugeTab = uitab(app.TabGroup);
            app.GaugeTab.Title = 'Gauge';
            app.Panel = uipanel(app.GaugeTab);
            app.GridLayout = uigridlayout(app.Panel);
            app.GCircLbl = uilabel(app.GridLayout);
            app.TInfoGC = uipanel(app.GridLayout);
            app.TInfoGC.Title = 'Timing Info';
            app.GridLayout_2 = uigridlayout(app.TInfoGC);
            app.GC_RecNumLbl = uilabel(app.GridLayout_2);
            app.GC_RecNumLbl.Text = 'Record#: ';
            app.GC_RecNumVal = uilabel(app.GridLayout_2);
            app.GC_RecNumVal.Text = {'000'; ''};
            app.GC_TimePerRecLbl = uilabel(app.GridLayout_2);
            app.GC_TimePerRecLbl.Text = 'Time/Rec: ';
            app.GC_TimePerRecVal = uilabel(app.GridLayout_2);
            app.GC_TimePerRecVal.Text = {'000'; ''};
            app.GC_TimePassedVal = uilabel(app.GridLayout_2);
            app.GC_TimePassedVal.Text = {'000'; ''};
            app.GC_TimePassedLbl = uilabel(app.GridLayout_2);
            app.GC_TimePassedLbl.Text = 'Passed Time:';
            app.GC_TimeRemLbl = uilabel(app.GridLayout_2);
            app.GC_TimeRemLbl.Text = 'Remaining Time:';
            app.GC_TimeRemVal = uilabel(app.GridLayout_2);
            app.GC_TimeRemVal.Text = {'000'; ''};
            app.GC_TotalDurLbl = uilabel(app.GridLayout_2);
            app.GC_TotalDurLbl.Text = 'Total Duration:';
            app.GC_TotalDurVal = uilabel(app.GridLayout_2);
            app.GC_TotalDurVal.Text = {'000'; ''};
            app.GCirc = uigauge(app.GridLayout, 'circular');
            app.GCOverflowLamp = uilamp(app.GridLayout);
            app.GCOverflowLamp.Visible = 'off';
            app.DegTab = uitab(app.TabGroup);
            app.DegTab.Title = '90 Deg';
            app.GridLayout2 = uigridlayout(app.DegTab);
            app.GC90DLbl = uilabel(app.GridLayout2);
            app.TInfoG9 = uipanel(app.GridLayout2);
            app.TInfoG9.Title = 'Timing Info';
            app.GridLayout_3 = uigridlayout(app.TInfoG9);
            app.G9_RecNumLbl = uilabel(app.GridLayout_3);
            app.G9_RecNumLbl.Text = 'Record#: ';
            app.G9_RecNumVal = uilabel(app.GridLayout_3);
            app.G9_RecNumVal.Text = {'000'; ''};
            app.G9_TimePerRecLbl = uilabel(app.GridLayout_3);
            app.G9_TimePerRecLbl.Text = 'Time/Rec: ';
            app.G9_TimePerRecVal = uilabel(app.GridLayout_3);
            app.G9_TimePerRecVal.Text = {'000'; ''};
            app.G9_TimePassedVal = uilabel(app.GridLayout_3);
            app.G9_TimePassedVal.Text = {'000'; ''};
            app.G9_TimePassedLbl = uilabel(app.GridLayout_3);
            app.G9_TimePassedLbl.Text = 'Passed Time:';
            app.G9_TimeRemLbl = uilabel(app.GridLayout_3);
            app.G9_TimeRemLbl.Text = 'Remaining Time:';
            app.G9_TimeRemVal = uilabel(app.GridLayout_3);
            app.G9_TimeRemVal.Text = {'000'; ''};
            app.G9_TotalDurLbl = uilabel(app.GridLayout_3);
            app.G9_TotalDurLbl.Text = 'Total Duration:';
            app.G9_TotalDurVal = uilabel(app.GridLayout_3);
            app.G9_TotalDurVal.Text = {'000'; ''};
            app.G9OverflowLamp = uilamp(app.GridLayout2);
            app.G9OverflowLamp.Visible = 'off';
            app.G90D = uigauge(app.GridLayout2, 'ninetydegree');
            app.LinearTab = uitab(app.TabGroup);
            app.LinearTab.Title = 'Linear';
            app.Panel_2 = uipanel(app.LinearTab);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.GLine = uigauge(app.GridLayout3, 'linear');
            app.TInfoGL = uipanel(app.GridLayout3);
            app.TInfoGL.Title = 'Timing Info';
            app.GridLayout_4 = uigridlayout(app.TInfoGL);
            app.GL_RecNumLbl = uilabel(app.GridLayout_4);
            app.GL_RecNumLbl.Text = 'Record#: ';
            app.GL_RecNumVal = uilabel(app.GridLayout_4);
            app.GL_RecNumVal.Text = {'000'; ''};
            app.GL_TimePerRecLbl = uilabel(app.GridLayout_4);
            app.GL_TimePerRecLbl.Text = 'Time/Rec: ';
            app.GL_TimePerRecVal = uilabel(app.GridLayout_4);
            app.GL_TimePerRecVal.Text = {'000'; ''};
            app.GL_TimePassedVal = uilabel(app.GridLayout_4);
            app.GL_TimePassedVal.Text = {'000'; ''};
            app.GL_TimePassedLbl = uilabel(app.GridLayout_4);
            app.GL_TimePassedLbl.Text = 'Passed Time:';
            app.GL_TimeRemLbl = uilabel(app.GridLayout_4);
            app.GL_TimeRemLbl.Text = 'Remaining Time:';
            app.GL_TimeRemVal = uilabel(app.GridLayout_4);
            app.GL_TimeRemVal.Text = {'000'; ''};
            app.GL_TotalDurLbl = uilabel(app.GridLayout_4);
            app.GL_TotalDurLbl.Text = 'Total Duration:';
            app.GL_TotalDurVal = uilabel(app.GridLayout_4);
            app.GL_TotalDurVal.Text = {'000'; ''};
            app.GLineLbl = uilabel(app.GridLayout3);
            app.GLOverflowLamp = uilamp(app.GridLayout3);
            app.GLOverflowLamp.Visible = 'off';
            app.SemiCircTab = uitab(app.TabGroup);
            app.SemiCircTab.Title = 'SemiCirc';
            app.Panel_3 = uipanel(app.SemiCircTab);
            app.GridLayout4 = uigridlayout(app.Panel_3);
            app.TInfoGC_2 = uipanel(app.GridLayout4);
            app.TInfoGC_2.Title = 'Timing Info';
            app.GridLayout_5 = uigridlayout(app.TInfoGC_2);
            app.GS_RecNumLbl = uilabel(app.GridLayout_5);
            app.GS_RecNumLbl.Text = 'Record#: ';
            app.GS_RecNumVal = uilabel(app.GridLayout_5);
            app.GS_RecNumVal.Text = {'000'; ''};
            app.GS_TimePerRecLbl = uilabel(app.GridLayout_5);
            app.GS_TimePerRecLbl.Text = 'Time/Rec: ';
            app.GS_TimePerRecVal = uilabel(app.GridLayout_5);
            app.GS_TimePerRecVal.Text = {'000'; ''};
            app.GS_TimePassedVal = uilabel(app.GridLayout_5);
            app.GS_TimePassedVal.Text = {'000'; ''};
            app.GS_TimePassedLbl = uilabel(app.GridLayout_5);
            app.GS_TimePassedLbl.Text = 'Passed Time:';
            app.GS_TimeRemLbl = uilabel(app.GridLayout_5);
            app.GS_TimeRemLbl.Text = 'Remaining Time:';
            app.GS_TimeRemVal = uilabel(app.GridLayout_5);
            app.GS_TimeRemVal.Text = {'000'; ''};
            app.GS_TotalDurLbl = uilabel(app.GridLayout_5);
            app.GS_TotalDurLbl.Text = 'Total Duration:';
            app.GS_TotalDurVal = uilabel(app.GridLayout_5);
            app.GS_TotalDurVal.Text = {'000'; ''};
            app.GSemiC = uigauge(app.GridLayout4, 'semicircular');
            app.GSCOverflowLamp = uilamp(app.GridLayout4);
            app.GSCOverflowLamp.Visible = 'off';
            app.GSemiCLbl = uilabel(app.GridLayout4);
            app.FastGaugeTab = uitab(app.TabGroup);
            app.FastGaugeTab.Title = 'Fast Gauge';
            app.GridLayout5 = uigridlayout(app.FastGaugeTab);
            app.TInfoG9_2 = uipanel(app.GridLayout5);
            app.TInfoG9_2.Title = 'Timing Info';
            app.GridLayout_6 = uigridlayout(app.TInfoG9_2);
            app.GF_RecNumLbl = uilabel(app.GridLayout_6);
            app.GF_RecNumLbl.Text = 'Record#: ';
            app.GF_RecNumVal = uilabel(app.GridLayout_6);
            app.GF_RecNumVal.Text = {'000'; ''};
            app.GF_TimePerRecLbl = uilabel(app.GridLayout_6);
            app.GF_TimePerRecLbl.Text = 'Time/Rec: ';
            app.GF_TimePerRecVal = uilabel(app.GridLayout_6);
            app.GF_TimePerRecVal.Text = {'000'; ''};
            app.GF_TimePassedVal = uilabel(app.GridLayout_6);
            app.GF_TimePassedVal.Text = {'000'; ''};
            app.GF_TimePassedLbl = uilabel(app.GridLayout_6);
            app.GF_TimePassedLbl.Text = 'Passed Time:';
            app.GF_TimeRemLbl = uilabel(app.GridLayout_6);
            app.GF_TimeRemLbl.Text = 'Remaining Time:';
            app.GF_TimeRemVal = uilabel(app.GridLayout_6);
            app.GF_TimeRemVal.Text = {'000'; ''};
            app.GF_TotalDurLbl = uilabel(app.GridLayout_6);
            app.GF_TotalDurLbl.Text = 'Total Duration:';
            app.GF_TotalDurVal = uilabel(app.GridLayout_6);
            app.GF_TotalDurVal.Text = {'000'; ''};
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.FastGauge1 = uigauge(app.GridLayout6, 'semicircular');
            app.FastGauge2 = uigauge(app.GridLayout6, 'circular');
            app.FastGauge2.Limits = [0 50];
            app.FastGauge2.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.FastGauge2.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.GFOverflowLamp = uilamp(app.GridLayout5);
            app.GFOverflowLamp.Visible = 'off';
            app.GCF0DLbl = uilabel(app.GridLayout5);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProgressIndicatorV34(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Startup_ProgressIndicator(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end